classdef arrangeFigApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        offStackFigsTab          matlab.ui.container.Tab
        fitmaxnumberButton       matlab.ui.control.Button
        offStackFigsButton       matlab.ui.control.Button
        colgapEditField_2        matlab.ui.control.NumericEditField
        colgapEditField_2Label   matlab.ui.control.Label
        hborderEditField         matlab.ui.control.NumericEditField
        hborderEditFieldLabel    matlab.ui.control.Label
        wborderEditField         matlab.ui.control.NumericEditField
        wborderEditFieldLabel    matlab.ui.control.Label
        hoffsetEditField         matlab.ui.control.NumericEditField
        hoffsetEditFieldLabel    matlab.ui.control.Label
        woffsetEditField         matlab.ui.control.NumericEditField
        woffsetEditFieldLabel    matlab.ui.control.Label
        heightEditField          matlab.ui.control.NumericEditField
        heightEditFieldLabel     matlab.ui.control.Label
        widthEditField           matlab.ui.control.NumericEditField
        widthEditFieldLabel      matlab.ui.control.Label
        tileFigsTab              matlab.ui.container.Tab
        fitmaxnumberButton_2     matlab.ui.control.Button
        tileFigsButton           matlab.ui.control.Button
        hborderEditField_2       matlab.ui.control.NumericEditField
        hborderLabel             matlab.ui.control.Label
        wborderEditField_2       matlab.ui.control.NumericEditField
        wborderLabel             matlab.ui.control.Label
        rowgapEditField          matlab.ui.control.NumericEditField
        rowgapLabel              matlab.ui.control.Label
        colgapEditField          matlab.ui.control.NumericEditField
        colgapLabel_2            matlab.ui.control.Label
        heightEditField_2        matlab.ui.control.NumericEditField
        heightEditField_2Label   matlab.ui.control.Label
        widthEditField_2         matlab.ui.control.NumericEditField
        widthEditField_2Label    matlab.ui.control.Label
        gridFigsTab              matlab.ui.container.Tab
        fitmaxnumberButton_3     matlab.ui.control.Button
        gridFigsButton           matlab.ui.control.Button
        hborderEditField_3       matlab.ui.control.NumericEditField
        hborderEditField_3Label  matlab.ui.control.Label
        wborderEditField_3       matlab.ui.control.NumericEditField
        wborderEditField_3Label  matlab.ui.control.Label
        rowgapEditField_2        matlab.ui.control.NumericEditField
        rowgapEditField_2Label   matlab.ui.control.Label
        colgapEditField_3        matlab.ui.control.NumericEditField
        colgapLabel              matlab.ui.control.Label
        numofrowEditField        matlab.ui.control.NumericEditField
        numofrowEditFieldLabel   matlab.ui.control.Label
        numofcolEditField        matlab.ui.control.NumericEditField
        numofcolEditFieldLabel   matlab.ui.control.Label
        layoutTab                matlab.ui.container.Tab
        calcpresetsButton        matlab.ui.control.Button
        SelectthesectionofthescreentoplacethefiguresLabel  matlab.ui.control.Label
        SelectedlayoutLabel      matlab.ui.control.Label
        TabGroup2                matlab.ui.container.TabGroup
        largeTab                 matlab.ui.container.Tab
        rightButton              matlab.ui.control.Button
        leftButton               matlab.ui.control.Button
        fullButton               matlab.ui.control.Button
        smallTab                 matlab.ui.container.Tab
        toprightButton           matlab.ui.control.Button
        bottomrightButton        matlab.ui.control.Button
        topleftButton            matlab.ui.control.Button
        bottomleftButton         matlab.ui.control.Button
        topButton                matlab.ui.control.Button
        bottomButton             matlab.ui.control.Button
        columsTab                matlab.ui.container.Tab
        rightrightButton         matlab.ui.control.Button
        leftButton_3             matlab.ui.control.Button
        rightButton_2            matlab.ui.control.Button
        leftleftButton           matlab.ui.control.Button
        rightthirdButton         matlab.ui.control.Button
        midthirdButton           matlab.ui.control.Button
        leftthirdButton          matlab.ui.control.Button
        fullLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: offStackFigsButton
        function offStackFigsButtonPushed(app, event)
            offStackFigs(app.widthEditField.Value,app.heightEditField.Value,app.woffsetEditField.Value,app.hoffsetEditField.Value,app.wborderEditField.Value,app.hborderEditField.Value,app.colgapEditField_2.Value,app.fullLabel.Text);
        end

        % Button pushed function: tileFigsButton
        function tileFigsButtonPushed(app, event)
            tileFigs(app.widthEditField_2.Value,app.heightEditField_2.Value,app.colgapEditField.Value,app.rowgapEditField.Value,app.wborderEditField_2.Value,app.hborderEditField_2.Value,app.fullLabel.Text);
        end

        % Button pushed function: gridFigsButton
        function gridFigsButtonPushed(app, event)
            gridFigs(app.numofcolEditField.Value,app.numofrowEditField.Value,app.colgapEditField_3.Value,app.rowgapEditField_2.Value,app.wborderEditField_3.Value,app.hborderEditField_3.Value,app.fullLabel.Text);
        end

        % Button pushed function: fullButton
        function fullButtonPushed(app, event)
            app.fullLabel.Text = "full";
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            app.fullLabel.Text = "left";
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            app.fullLabel.Text = "right";
        end

        % Button pushed function: topButton
        function topButtonPushed(app, event)
            app.fullLabel.Text = "top";
        end

        % Button pushed function: bottomButton
        function bottomButtonPushed(app, event)
            app.fullLabel.Text = "bottom";
        end

        % Button pushed function: topleftButton
        function topleftButtonPushed(app, event)
            app.fullLabel.Text = "topleft";
        end

        % Button pushed function: toprightButton
        function toprightButtonPushed(app, event)
            app.fullLabel.Text = "topright";
        end

        % Button pushed function: bottomleftButton
        function bottomleftButtonPushed(app, event)
            app.fullLabel.Text = "bottomleft";
        end

        % Button pushed function: bottomrightButton
        function bottomrightButtonPushed(app, event)
            app.fullLabel.Text = "bottomright";
        end

        % Button pushed function: leftthirdButton
        function leftthirdButtonPushed(app, event)
            app.fullLabel.Text = "lthird";
        end

        % Button pushed function: midthirdButton
        function midthirdButtonPushed(app, event)
            app.fullLabel.Text = "mthird";
        end

        % Button pushed function: rightthirdButton
        function rightthirdButtonPushed(app, event)
            app.fullLabel.Text = "rthird";
        end

        % Button pushed function: leftleftButton
        function leftleftButtonPushed(app, event)
            app.fullLabel.Text = "llquart";
        end

        % Button pushed function: leftButton_3
        function leftButton_3Pushed(app, event)
            app.fullLabel.Text = "lquart";
        end

        % Button pushed function: rightButton_2
        function rightButton_2Pushed(app, event)
            app.fullLabel.Text = "rquart";
        end

        % Button pushed function: rightrightButton
        function rightrightButtonPushed(app, event)
            app.fullLabel.Text = "rrquart";
        end

        % Button pushed function: calcpresetsButton
        function calcpresetsButtonPushed(app, event)
            [gridFigs_preset, tileFigs_preset, offStackFigs_preset] = presetCalc_screenSize(app.fullLabel.Text);
            %offStackFigs
            app.widthEditField.Value = offStackFigs_preset.xlen;
            app.heightEditField.Value = offStackFigs_preset.ylen;
            app.woffsetEditField.Value = offStackFigs_preset.xOffSet; 
            app.hoffsetEditField.Value = offStackFigs_preset.yOffSet;
            app.wborderEditField.Value = offStackFigs_preset.xborder;
            app.hborderEditField.Value = offStackFigs_preset.yborder;
            app.colgapEditField_2.Value = offStackFigs_preset.colOffSet;
            %tileFigs
            app.widthEditField_2.Value = tileFigs_preset.xlen;
            app.heightEditField_2.Value = tileFigs_preset.ylen;
            app.colgapEditField.Value = tileFigs_preset.colOffSet;
            app.rowgapEditField.Value = tileFigs_preset.rowOffSet;
            app.wborderEditField_2.Value = tileFigs_preset.xborder;
            app.hborderEditField_2.Value = tileFigs_preset.yborder;
            %gridFigs
            app.numofcolEditField.Value = gridFigs_preset.nCol;
            app.numofrowEditField.Value = gridFigs_preset.nRow;
            app.colgapEditField_3.Value = gridFigs_preset.colOffSet;
            app.rowgapEditField_2.Value = gridFigs_preset.rowOffSet;
            app.wborderEditField_3.Value = gridFigs_preset.xborder;
            app.hborderEditField_3.Value = gridFigs_preset.yborder;
        end

        % Button pushed function: fitmaxnumberButton
        function fitmaxnumberButtonPushed(app, event)
            [~,~,offStackFigs_preset] = maxFitCalc_screenSize(app.fullLabel.Text);
            offStackFigs(offStackFigs_preset.xlen,offStackFigs_preset.ylen,offStackFigs_preset.xOffSet,offStackFigs_preset.yOffSet,offStackFigs_preset.xborder,offStackFigs_preset.yborder,offStackFigs_preset.colOffSet,app.fullLabel.Text);
        end

        % Button pushed function: fitmaxnumberButton_3
        function fitmaxnumberButton_3Pushed(app, event)
            [gridFigs_preset, ~,~] = maxFitCalc_screenSize(app.fullLabel.Text);
            gridFigs(gridFigs_preset.nCol,gridFigs_preset.nRow,gridFigs_preset.colOffSet,gridFigs_preset.rowOffSet,gridFigs_preset.xborder,gridFigs_preset.yborder,app.fullLabel.Text);
        end

        % Button pushed function: fitmaxnumberButton_2
        function fitmaxnumberButton_2Pushed(app, event)
            [~, tileFigs_preset, ~] = maxFitCalc_screenSize(app.fullLabel.Text);
            tileFigs(tileFigs_preset.xlen,tileFigs_preset.ylen,tileFigs_preset.colOffSet,tileFigs_preset.rowOffSet,tileFigs_preset.xborder,tileFigs_preset.yborder,app.fullLabel.Text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.offStackFigsTab = uitab(app.TabGroup);
            app.offStackFigsTab.Title = 'offStackFigs';
            app.widthEditFieldLabel = uilabel(app.offStackFigsTab);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.offStackFigsTab, 'numeric');
            app.widthEditField.Value = 560;
            app.heightEditFieldLabel = uilabel(app.offStackFigsTab);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.offStackFigsTab, 'numeric');
            app.heightEditField.Value = 420;
            app.woffsetEditFieldLabel = uilabel(app.offStackFigsTab);
            app.woffsetEditFieldLabel.Text = 'w-offset';
            app.woffsetEditField = uieditfield(app.offStackFigsTab, 'numeric');
            app.hoffsetEditFieldLabel = uilabel(app.offStackFigsTab);
            app.hoffsetEditFieldLabel.Text = 'h-offset';
            app.hoffsetEditField = uieditfield(app.offStackFigsTab, 'numeric');
            app.hoffsetEditField.Value = 25;
            app.wborderEditFieldLabel = uilabel(app.offStackFigsTab);
            app.wborderEditFieldLabel.Text = 'w-border';
            app.wborderEditField = uieditfield(app.offStackFigsTab, 'numeric');
            app.wborderEditField.Value = 25;
            app.hborderEditFieldLabel = uilabel(app.offStackFigsTab);
            app.hborderEditFieldLabel.Text = 'h-border';
            app.hborderEditField = uieditfield(app.offStackFigsTab, 'numeric');
            app.hborderEditField.Value = 25;
            app.colgapEditField_2Label = uilabel(app.offStackFigsTab);
            app.colgapEditField_2Label.Text = 'col-gap';
            app.colgapEditField_2 = uieditfield(app.offStackFigsTab, 'numeric');
            app.colgapEditField_2.Value = 25;
            app.offStackFigsButton = uibutton(app.offStackFigsTab, 'push');
            app.offStackFigsButton.ButtonPushedFcn = createCallbackFcn(app, @offStackFigsButtonPushed, true);
            app.offStackFigsButton.Text = 'offStackFigs';
            app.fitmaxnumberButton = uibutton(app.offStackFigsTab, 'push');
            app.fitmaxnumberButton.ButtonPushedFcn = createCallbackFcn(app, @fitmaxnumberButtonPushed, true);
            app.fitmaxnumberButton.Text = 'fit max number';
            app.tileFigsTab = uitab(app.TabGroup);
            app.tileFigsTab.Title = 'tileFigs';
            app.widthEditField_2Label = uilabel(app.tileFigsTab);
            app.widthEditField_2Label.Text = 'width';
            app.widthEditField_2 = uieditfield(app.tileFigsTab, 'numeric');
            app.widthEditField_2.Value = 560;
            app.heightEditField_2Label = uilabel(app.tileFigsTab);
            app.heightEditField_2Label.Text = 'height';
            app.heightEditField_2 = uieditfield(app.tileFigsTab, 'numeric');
            app.heightEditField_2.Value = 420;
            app.colgapLabel_2 = uilabel(app.tileFigsTab);
            app.colgapLabel_2.Text = 'col - gap';
            app.colgapEditField = uieditfield(app.tileFigsTab, 'numeric');
            app.rowgapLabel = uilabel(app.tileFigsTab);
            app.rowgapLabel.Text = 'row - gap';
            app.rowgapEditField = uieditfield(app.tileFigsTab, 'numeric');
            app.wborderLabel = uilabel(app.tileFigsTab);
            app.wborderLabel.Text = 'w - border';
            app.wborderEditField_2 = uieditfield(app.tileFigsTab, 'numeric');
            app.wborderEditField_2.Value = 25;
            app.hborderLabel = uilabel(app.tileFigsTab);
            app.hborderLabel.Text = 'h - border';
            app.hborderEditField_2 = uieditfield(app.tileFigsTab, 'numeric');
            app.hborderEditField_2.Value = 25;
            app.tileFigsButton = uibutton(app.tileFigsTab, 'push');
            app.tileFigsButton.ButtonPushedFcn = createCallbackFcn(app, @tileFigsButtonPushed, true);
            app.tileFigsButton.Text = 'tileFigs';
            app.fitmaxnumberButton_2 = uibutton(app.tileFigsTab, 'push');
            app.fitmaxnumberButton_2.ButtonPushedFcn = createCallbackFcn(app, @fitmaxnumberButton_2Pushed, true);
            app.fitmaxnumberButton_2.Text = 'fit max number';
            app.gridFigsTab = uitab(app.TabGroup);
            app.gridFigsTab.Title = 'gridFigs';
            app.numofcolEditFieldLabel = uilabel(app.gridFigsTab);
            app.numofcolEditFieldLabel.Text = 'num of col';
            app.numofcolEditField = uieditfield(app.gridFigsTab, 'numeric');
            app.numofcolEditField.Value = 3;
            app.numofrowEditFieldLabel = uilabel(app.gridFigsTab);
            app.numofrowEditFieldLabel.Text = 'num  of row';
            app.numofrowEditField = uieditfield(app.gridFigsTab, 'numeric');
            app.numofrowEditField.Value = 2;
            app.colgapLabel = uilabel(app.gridFigsTab);
            app.colgapLabel.Text = 'col - gap';
            app.colgapEditField_3 = uieditfield(app.gridFigsTab, 'numeric');
            app.rowgapEditField_2Label = uilabel(app.gridFigsTab);
            app.rowgapEditField_2Label.Text = 'row - gap';
            app.rowgapEditField_2 = uieditfield(app.gridFigsTab, 'numeric');
            app.wborderEditField_3Label = uilabel(app.gridFigsTab);
            app.wborderEditField_3Label.Text = 'w - border';
            app.wborderEditField_3 = uieditfield(app.gridFigsTab, 'numeric');
            app.wborderEditField_3.Value = 25;
            app.hborderEditField_3Label = uilabel(app.gridFigsTab);
            app.hborderEditField_3Label.Text = 'h - border';
            app.hborderEditField_3 = uieditfield(app.gridFigsTab, 'numeric');
            app.hborderEditField_3.Value = 25;
            app.gridFigsButton = uibutton(app.gridFigsTab, 'push');
            app.gridFigsButton.ButtonPushedFcn = createCallbackFcn(app, @gridFigsButtonPushed, true);
            app.gridFigsButton.Text = 'gridFigs';
            app.fitmaxnumberButton_3 = uibutton(app.gridFigsTab, 'push');
            app.fitmaxnumberButton_3.ButtonPushedFcn = createCallbackFcn(app, @fitmaxnumberButton_3Pushed, true);
            app.fitmaxnumberButton_3.Text = 'fit max number';
            app.layoutTab = uitab(app.TabGroup);
            app.layoutTab.Title = 'layout';
            app.fullLabel = uilabel(app.layoutTab);
            app.fullLabel.Text = 'full';
            app.TabGroup2 = uitabgroup(app.layoutTab);
            app.largeTab = uitab(app.TabGroup2);
            app.largeTab.Title = 'large';
            app.fullButton = uibutton(app.largeTab, 'push');
            app.fullButton.ButtonPushedFcn = createCallbackFcn(app, @fullButtonPushed, true);
            app.fullButton.Text = 'full';
            app.leftButton = uibutton(app.largeTab, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = 'left';
            app.rightButton = uibutton(app.largeTab, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = 'right';
            app.smallTab = uitab(app.TabGroup2);
            app.smallTab.Title = 'small';
            app.bottomButton = uibutton(app.smallTab, 'push');
            app.bottomButton.ButtonPushedFcn = createCallbackFcn(app, @bottomButtonPushed, true);
            app.bottomButton.Text = 'bottom';
            app.topButton = uibutton(app.smallTab, 'push');
            app.topButton.ButtonPushedFcn = createCallbackFcn(app, @topButtonPushed, true);
            app.topButton.Text = 'top';
            app.bottomleftButton = uibutton(app.smallTab, 'push');
            app.bottomleftButton.ButtonPushedFcn = createCallbackFcn(app, @bottomleftButtonPushed, true);
            app.bottomleftButton.Text = {'bottom'; 'left'};
            app.topleftButton = uibutton(app.smallTab, 'push');
            app.topleftButton.ButtonPushedFcn = createCallbackFcn(app, @topleftButtonPushed, true);
            app.topleftButton.Text = {'top'; 'left'};
            app.bottomrightButton = uibutton(app.smallTab, 'push');
            app.bottomrightButton.ButtonPushedFcn = createCallbackFcn(app, @bottomrightButtonPushed, true);
            app.bottomrightButton.Text = {'bottom'; 'right'};
            app.toprightButton = uibutton(app.smallTab, 'push');
            app.toprightButton.ButtonPushedFcn = createCallbackFcn(app, @toprightButtonPushed, true);
            app.toprightButton.Text = {'top'; 'right'};
            app.columsTab = uitab(app.TabGroup2);
            app.columsTab.Title = 'colums';
            app.leftthirdButton = uibutton(app.columsTab, 'push');
            app.leftthirdButton.ButtonPushedFcn = createCallbackFcn(app, @leftthirdButtonPushed, true);
            app.leftthirdButton.Text = {'left'; 'third'};
            app.midthirdButton = uibutton(app.columsTab, 'push');
            app.midthirdButton.ButtonPushedFcn = createCallbackFcn(app, @midthirdButtonPushed, true);
            app.midthirdButton.Text = {'mid'; 'third'};
            app.rightthirdButton = uibutton(app.columsTab, 'push');
            app.rightthirdButton.ButtonPushedFcn = createCallbackFcn(app, @rightthirdButtonPushed, true);
            app.rightthirdButton.Text = {'right'; 'third'};
            app.leftleftButton = uibutton(app.columsTab, 'push');
            app.leftleftButton.ButtonPushedFcn = createCallbackFcn(app, @leftleftButtonPushed, true);
            app.leftleftButton.Text = {'left'; 'left'};
            app.rightButton_2 = uibutton(app.columsTab, 'push');
            app.rightButton_2.ButtonPushedFcn = createCallbackFcn(app, @rightButton_2Pushed, true);
            app.rightButton_2.Text = 'right';
            app.leftButton_3 = uibutton(app.columsTab, 'push');
            app.leftButton_3.ButtonPushedFcn = createCallbackFcn(app, @leftButton_3Pushed, true);
            app.leftButton_3.Text = 'left';
            app.rightrightButton = uibutton(app.columsTab, 'push');
            app.rightrightButton.ButtonPushedFcn = createCallbackFcn(app, @rightrightButtonPushed, true);
            app.rightrightButton.Text = {'right'; 'right'};
            app.SelectedlayoutLabel = uilabel(app.layoutTab);
            app.SelectedlayoutLabel.Text = 'Selected layout:';
            app.SelectthesectionofthescreentoplacethefiguresLabel = uilabel(app.layoutTab);
            app.SelectthesectionofthescreentoplacethefiguresLabel.Text = 'Select the section of the screen to place the figures:';
            app.calcpresetsButton = uibutton(app.layoutTab, 'push');
            app.calcpresetsButton.ButtonPushedFcn = createCallbackFcn(app, @calcpresetsButtonPushed, true);
            app.calcpresetsButton.Text = 'calc. presets';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arrangeFigApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end